classdef Bumps4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BumpsSimulatorUIFigure  matlab.ui.Figure
        Chart                   matlab.ui.control.UIAxes
        SimulateButton          matlab.ui.control.Button
        BoatInfo                matlab.ui.container.Panel
        Label                   matlab.ui.control.Label
        College_1               matlab.ui.control.DropDown
        Label_5                 matlab.ui.control.Label
        Speed_1                 matlab.ui.control.NumericEditField
        Label_6                 matlab.ui.control.Label
        College_2               matlab.ui.control.DropDown
        Label_7                 matlab.ui.control.Label
        Speed_2                 matlab.ui.control.NumericEditField
        Label_8                 matlab.ui.control.Label
        College_3               matlab.ui.control.DropDown
        Label_9                 matlab.ui.control.Label
        Speed_3                 matlab.ui.control.NumericEditField
        Label_10                matlab.ui.control.Label
        College_4               matlab.ui.control.DropDown
        Label_11                matlab.ui.control.Label
        Speed_4                 matlab.ui.control.NumericEditField
        Label_12                matlab.ui.control.Label
        College_5               matlab.ui.control.DropDown
        Label_13                matlab.ui.control.Label
        Speed_5                 matlab.ui.control.NumericEditField
        Label_14                matlab.ui.control.Label
        College_6               matlab.ui.control.DropDown
        Label_15                matlab.ui.control.Label
        Speed_6                 matlab.ui.control.NumericEditField
        Label_16                matlab.ui.control.Label
        College_7               matlab.ui.control.DropDown
        Label_17                matlab.ui.control.Label
        Speed_7                 matlab.ui.control.NumericEditField
        Label_18                matlab.ui.control.Label
        College_8               matlab.ui.control.DropDown
        Label_19                matlab.ui.control.Label
        Speed_8                 matlab.ui.control.NumericEditField
        Label_20                matlab.ui.control.Label
        College_9               matlab.ui.control.DropDown
        Label_21                matlab.ui.control.Label
        Speed_9                 matlab.ui.control.NumericEditField
        Label_22                matlab.ui.control.Label
        College_10              matlab.ui.control.DropDown
        Label_23                matlab.ui.control.Label
        Speed_10                matlab.ui.control.NumericEditField
        Label_24                matlab.ui.control.Label
        College_11              matlab.ui.control.DropDown
        Label_25                matlab.ui.control.Label
        Speed_11                matlab.ui.control.NumericEditField
        Label_26                matlab.ui.control.Label
        College_12              matlab.ui.control.DropDown
        Label_27                matlab.ui.control.Label
        Speed_12                matlab.ui.control.NumericEditField
        Label_28                matlab.ui.control.Label
        College_13              matlab.ui.control.DropDown
        Label_29                matlab.ui.control.Label
        Speed_13                matlab.ui.control.NumericEditField
        Label_30                matlab.ui.control.Label
        College_14              matlab.ui.control.DropDown
        Label_31                matlab.ui.control.Label
        Speed_14                matlab.ui.control.NumericEditField
        Label_32                matlab.ui.control.Label
        College_15              matlab.ui.control.DropDown
        Label_33                matlab.ui.control.Label
        Speed_15                matlab.ui.control.NumericEditField
        Label_34                matlab.ui.control.Label
        College_16              matlab.ui.control.DropDown
        Label_35                matlab.ui.control.Label
        Speed_16                matlab.ui.control.NumericEditField
        Label_36                matlab.ui.control.Label
        College_17              matlab.ui.control.DropDown
        Label_37                matlab.ui.control.Label
        Speed_17                matlab.ui.control.NumericEditField
        RaceButtonGroup         matlab.ui.container.ButtonGroup
        MenButton               matlab.ui.control.ToggleButton
        WomenButton             matlab.ui.control.ToggleButton
        DivisionButtonGroup     matlab.ui.container.ButtonGroup
        Button                  matlab.ui.control.ToggleButton
        Button_2                matlab.ui.control.ToggleButton
        Button_3                matlab.ui.control.ToggleButton
        Button_4                matlab.ui.control.ToggleButton
        Button_5                matlab.ui.control.ToggleButton
        DaysSpinnerLabel        matlab.ui.control.Label
        DaysSpinner             matlab.ui.control.Spinner
        BoatsSpinnerLabel       matlab.ui.control.Label
        BoatsSpinner            matlab.ui.control.Spinner
        UpdateTableButton       matlab.ui.control.Button
        BoatInfo_2              matlab.ui.container.Panel
        Result_1                matlab.ui.control.Label
        Result_2                matlab.ui.control.Label
        Result_3                matlab.ui.control.Label
        Result_4                matlab.ui.control.Label
        Result_5                matlab.ui.control.Label
        Result_6                matlab.ui.control.Label
        Result_7                matlab.ui.control.Label
        Result_8                matlab.ui.control.Label
        Result_9                matlab.ui.control.Label
        Result_10               matlab.ui.control.Label
        Result_11               matlab.ui.control.Label
        Result_12               matlab.ui.control.Label
        Result_13               matlab.ui.control.Label
        Result_14               matlab.ui.control.Label
        Result_15               matlab.ui.control.Label
        Result_16               matlab.ui.control.Label
        Result_17               matlab.ui.control.Label
        AboutButton             matlab.ui.control.Button
        TermButtonGroup         matlab.ui.container.ButtonGroup
        LentButton              matlab.ui.control.ToggleButton
        MayButton               matlab.ui.control.ToggleButton
        YearSpinnerLabel        matlab.ui.control.Label
        YearSpinner             matlab.ui.control.Spinner
    end

    
    properties (Access = public)
        NumBoats; % Number of boats
        NumDays; % Number of days of racing
        Boats; % Holds all boat information
        
        MaxBoats = 17;
        needReadCUCBC = false; % Year/term changed; need to update start order
    end
    
    methods (Access = private)
        
        function showColleges(app)
            % Setup NumBoats
            app.NumBoats = app.BoatsSpinner.Value;
            for b = 1:app.MaxBoats
                if b <= app.NumBoats
                    if strcmp(app.(['College_' num2str(b)]).Visible,'off')
                        app.(['College_' num2str(b)]).Visible = 'on';
                        app.(['Speed_' num2str(b)]).Visible = 'on';
                        app.(['Result_' num2str(b)]).Visible = 'on';
                        app.(['Result_' num2str(b)]).Text = '';
                    end
                else
                    app.(['College_' num2str(b)]).Visible = 'off';
                    app.(['Speed_' num2str(b)]).Visible = 'off';
                    app.(['Result_' num2str(b)]).Visible = 'off';
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.NumBoats = app.BoatsSpinner.Value;
            assignBoats(app);
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            showColleges(app);
            app.NumDays = app.DaysSpinner.Value;
            
            % Get boat data
            app.Boats = cell(1,app.NumBoats);
            for b = 1:app.NumBoats
                app.Boats{b} = Boat(app,b);
            end
            
            % Simulate bumps
            for d = 1:app.NumDays
                simulateBumps(app);
            end
            
            % Display chart
            % Title is 'May Bumps 2019: W3'
            app.Chart.Title.String = [app.TermButtonGroup.SelectedObject.Text ' Bumps ' num2str(app.YearSpinner.Value) ': '...
                                        app.RaceButtonGroup.SelectedObject.Text(1) app.DivisionButtonGroup.SelectedObject.Text(1)];
            cla(app.Chart);
            hold(app.Chart,'on');
            plotBoats(app);
            
            for b = 1:app.NumBoats
                finalPos = num2str(app.Boats{b}.Position(end));
                app.(['Result_' finalPos]).Text = [finalPos '. ' app.Boats{b}.College];
            end
        end

        % Button pushed function: UpdateTableButton
        function UpdateTableButtonPushed(app, event)
            if app.needReadCUCBC
                readCUCBC(app);
                app.needReadCUCBC = false;
            end
            
            showColleges(app);
            
            assignBoats(app);
        end

        % Button pushed function: AboutButton
        function AboutButtonPushed(app, event)
            msgbox(['Welcome to the Bumps Simulator!' ...
                newline ...
                'Choose your crews and assign speeds. Speed is approximately the distance rowed in metres in two minutes; '...
                'a difference of 8 is required for a bump, and 58 for an overbump. '...
                newline ...
                'Click ''Update Table'' to refresh the start order from the CUCBC website. '...
                'If no crew exists for a particular division and position, its college will be randomly allocated.'],...
                'About Bumps Simulator','help');
        end

        % Callback function
        function UpdateOrderButtonPushed(app, event)
            
        end

        % Value changed function: YearSpinner
        function YearSpinnerValueChanged(app, event)
            app.needReadCUCBC = true;
        end

        % Selection changed function: TermButtonGroup
        function TermButtonGroupSelectionChanged(app, event)
            app.needReadCUCBC = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BumpsSimulatorUIFigure = uifigure('Visible', 'off');
            app.BumpsSimulatorUIFigure.Name = 'Bumps Simulator';
            app.Chart = uiaxes(app.BumpsSimulatorUIFigure);
            title(app.Chart, 'Bumps Chart')
            xlabel(app.Chart, 'Day')
            ylabel(app.Chart, 'Position')
            app.Chart.XLim = [0 4];
            app.Chart.YLim = [0 17];
            app.Chart.YDir = 'reverse';
            app.Chart.XTick = [0 1 2 3 4];
            app.Chart.YTick = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17];
            app.SimulateButton = uibutton(app.BumpsSimulatorUIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.BoatInfo = uipanel(app.BumpsSimulatorUIFigure);
            app.BoatInfo.Title = 'Boat speed';
            app.Label = uilabel(app.BoatInfo);
            app.Label.Text = '1';
            app.College_1 = uidropdown(app.BoatInfo);
            app.College_1.Items = {'Anglia Ruskin', 'Caius', 'Christ''s', 'Churchill', 'Clare', 'Clare Hall', 'Corpus Christi', 'Darwin', 'Downing', 'Emmanuel', 'First and Third', 'Fitzwilliam', 'Girton', 'Homerton', 'Hughes Hall', 'Jesus', 'King''s', 'Lady Margaret', 'Lucy Cavendish', 'Magdalene', 'Murray Edwards', 'Newnham', 'Pembroke', 'Peterhouse', 'Queens''', 'Robinson', 'St. Catharine''s', 'St. Edmund''s', 'Selwyn', 'Sidney Sussex', 'Trinity Hall', 'Wolfson'};
            app.College_1.Value = 'Murray Edwards';
            app.Label_5 = uilabel(app.BoatInfo);
            app.Label_5.Text = '';
            app.Speed_1 = uieditfield(app.BoatInfo, 'numeric');
            app.Speed_1.Value = 500;
            app.Label_6 = uilabel(app.BoatInfo);
            app.Label_6.Text = '2';
            app.College_2 = uidropdown(app.BoatInfo);
            app.College_2.Items = {'Anglia Ruskin', 'Caius', 'Christ''s', 'Churchill', 'Clare', 'Clare Hall', 'Corpus Christi', 'Darwin', 'Downing', 'Emmanuel', 'First and Third', 'Fitzwilliam', 'Girton', 'Homerton', 'Hughes Hall', 'Jesus', 'King''s', 'Lady Margaret', 'Lucy Cavendish', 'Magdalene', 'Murray Edwards', 'Newnham', 'Pembroke', 'Peterhouse', 'Queens''', 'Robinson', 'St. Catharine''s', 'St. Edmund''s', 'Selwyn', 'Sidney Sussex', 'Trinity Hall', 'Wolfson'};
            app.College_2.Value = 'Murray Edwards';
            app.Label_7 = uilabel(app.BoatInfo);
            app.Label_7.Text = '';
            app.Speed_2 = uieditfield(app.BoatInfo, 'numeric');
            app.Speed_2.Value = 500;
            app.Label_8 = uilabel(app.BoatInfo);
            app.Label_8.Text = '3';
            app.College_3 = uidropdown(app.BoatInfo);
            app.College_3.Items = {'Anglia Ruskin', 'Caius', 'Christ''s', 'Churchill', 'Clare', 'Clare Hall', 'Corpus Christi', 'Darwin', 'Downing', 'Emmanuel', 'First and Third', 'Fitzwilliam', 'Girton', 'Homerton', 'Hughes Hall', 'Jesus', 'King''s', 'Lady Margaret', 'Lucy Cavendish', 'Magdalene', 'Murray Edwards', 'Newnham', 'Pembroke', 'Peterhouse', 'Queens''', 'Robinson', 'St. Catharine''s', 'St. Edmund''s', 'Selwyn', 'Sidney Sussex', 'Trinity Hall', 'Wolfson'};
            app.College_3.Value = 'Murray Edwards';
            app.Label_9 = uilabel(app.BoatInfo);
            app.Label_9.Text = '';
            app.Speed_3 = uieditfield(app.BoatInfo, 'numeric');
            app.Speed_3.Value = 500;
            app.Label_10 = uilabel(app.BoatInfo);
            app.Label_10.Text = '4';
            app.College_4 = uidropdown(app.BoatInfo);
            app.College_4.Items = {'Anglia Ruskin', 'Caius', 'Christ''s', 'Churchill', 'Clare', 'Clare Hall', 'Corpus Christi', 'Darwin', 'Downing', 'Emmanuel', 'First and Third', 'Fitzwilliam', 'Girton', 'Homerton', 'Hughes Hall', 'Jesus', 'King''s', 'Lady Margaret', 'Lucy Cavendish', 'Magdalene', 'Murray Edwards', 'Newnham', 'Pembroke', 'Peterhouse', 'Queens''', 'Robinson', 'St. Catharine''s', 'St. Edmund''s', 'Selwyn', 'Sidney Sussex', 'Trinity Hall', 'Wolfson'};
            app.College_4.Value = 'Murray Edwards';
            app.Label_11 = uilabel(app.BoatInfo);
            app.Label_11.Text = '';
            app.Speed_4 = uieditfield(app.BoatInfo, 'numeric');
            app.Speed_4.Value = 500;
            app.Label_12 = uilabel(app.BoatInfo);
            app.Label_12.Text = '5';
            app.College_5 = uidropdown(app.BoatInfo);
            app.College_5.Items = {'Anglia Ruskin', 'Caius', 'Christ''s', 'Churchill', 'Clare', 'Clare Hall', 'Corpus Christi', 'Darwin', 'Downing', 'Emmanuel', 'First and Third', 'Fitzwilliam', 'Girton', 'Homerton', 'Hughes Hall', 'Jesus', 'King''s', 'Lady Margaret', 'Lucy Cavendish', 'Magdalene', 'Murray Edwards', 'Newnham', 'Pembroke', 'Peterhouse', 'Queens''', 'Robinson', 'St. Catharine''s', 'St. Edmund''s', 'Selwyn', 'Sidney Sussex', 'Trinity Hall', 'Wolfson'};
            app.College_5.Value = 'Murray Edwards';
            app.Label_13 = uilabel(app.BoatInfo);
            app.Label_13.Text = '';
            app.Speed_5 = uieditfield(app.BoatInfo, 'numeric');
            app.Speed_5.Value = 500;
            app.Label_14 = uilabel(app.BoatInfo);
            app.Label_14.Text = '6';
            app.College_6 = uidropdown(app.BoatInfo);
            app.College_6.Items = {'Anglia Ruskin', 'Caius', 'Christ''s', 'Churchill', 'Clare', 'Clare Hall', 'Corpus Christi', 'Darwin', 'Downing', 'Emmanuel', 'First and Third', 'Fitzwilliam', 'Girton', 'Homerton', 'Hughes Hall', 'Jesus', 'King''s', 'Lady Margaret', 'Lucy Cavendish', 'Magdalene', 'Murray Edwards', 'Newnham', 'Pembroke', 'Peterhouse', 'Queens''', 'Robinson', 'St. Catharine''s', 'St. Edmund''s', 'Selwyn', 'Sidney Sussex', 'Trinity Hall', 'Wolfson'};
            app.College_6.Value = 'Murray Edwards';
            app.Label_15 = uilabel(app.BoatInfo);
            app.Label_15.Text = '';
            app.Speed_6 = uieditfield(app.BoatInfo, 'numeric');
            app.Speed_6.Value = 500;
            app.Label_16 = uilabel(app.BoatInfo);
            app.Label_16.Text = '7';
            app.College_7 = uidropdown(app.BoatInfo);
            app.College_7.Items = {'Anglia Ruskin', 'Caius', 'Christ''s', 'Churchill', 'Clare', 'Clare Hall', 'Corpus Christi', 'Darwin', 'Downing', 'Emmanuel', 'First and Third', 'Fitzwilliam', 'Girton', 'Homerton', 'Hughes Hall', 'Jesus', 'King''s', 'Lady Margaret', 'Lucy Cavendish', 'Magdalene', 'Murray Edwards', 'Newnham', 'Pembroke', 'Peterhouse', 'Queens''', 'Robinson', 'St. Catharine''s', 'St. Edmund''s', 'Selwyn', 'Sidney Sussex', 'Trinity Hall', 'Wolfson'};
            app.College_7.Value = 'Murray Edwards';
            app.Label_17 = uilabel(app.BoatInfo);
            app.Label_17.Text = '';
            app.Speed_7 = uieditfield(app.BoatInfo, 'numeric');
            app.Speed_7.Value = 500;
            app.Label_18 = uilabel(app.BoatInfo);
            app.Label_18.Text = '8';
            app.College_8 = uidropdown(app.BoatInfo);
            app.College_8.Items = {'Anglia Ruskin', 'Caius', 'Christ''s', 'Churchill', 'Clare', 'Clare Hall', 'Corpus Christi', 'Darwin', 'Downing', 'Emmanuel', 'First and Third', 'Fitzwilliam', 'Girton', 'Homerton', 'Hughes Hall', 'Jesus', 'King''s', 'Lady Margaret', 'Lucy Cavendish', 'Magdalene', 'Murray Edwards', 'Newnham', 'Pembroke', 'Peterhouse', 'Queens''', 'Robinson', 'St. Catharine''s', 'St. Edmund''s', 'Selwyn', 'Sidney Sussex', 'Trinity Hall', 'Wolfson'};
            app.College_8.Value = 'Murray Edwards';
            app.Label_19 = uilabel(app.BoatInfo);
            app.Label_19.Text = '';
            app.Speed_8 = uieditfield(app.BoatInfo, 'numeric');
            app.Speed_8.Value = 500;
            app.Label_20 = uilabel(app.BoatInfo);
            app.Label_20.Text = '9';
            app.College_9 = uidropdown(app.BoatInfo);
            app.College_9.Items = {'Anglia Ruskin', 'Caius', 'Christ''s', 'Churchill', 'Clare', 'Clare Hall', 'Corpus Christi', 'Darwin', 'Downing', 'Emmanuel', 'First and Third', 'Fitzwilliam', 'Girton', 'Homerton', 'Hughes Hall', 'Jesus', 'King''s', 'Lady Margaret', 'Lucy Cavendish', 'Magdalene', 'Murray Edwards', 'Newnham', 'Pembroke', 'Peterhouse', 'Queens''', 'Robinson', 'St. Catharine''s', 'St. Edmund''s', 'Selwyn', 'Sidney Sussex', 'Trinity Hall', 'Wolfson'};
            app.College_9.Value = 'Murray Edwards';
            app.Label_21 = uilabel(app.BoatInfo);
            app.Label_21.Text = '';
            app.Speed_9 = uieditfield(app.BoatInfo, 'numeric');
            app.Speed_9.Value = 500;
            app.Label_22 = uilabel(app.BoatInfo);
            app.Label_22.Text = '10';
            app.College_10 = uidropdown(app.BoatInfo);
            app.College_10.Items = {'Anglia Ruskin', 'Caius', 'Christ''s', 'Churchill', 'Clare', 'Clare Hall', 'Corpus Christi', 'Darwin', 'Downing', 'Emmanuel', 'First and Third', 'Fitzwilliam', 'Girton', 'Homerton', 'Hughes Hall', 'Jesus', 'King''s', 'Lady Margaret', 'Lucy Cavendish', 'Magdalene', 'Murray Edwards', 'Newnham', 'Pembroke', 'Peterhouse', 'Queens''', 'Robinson', 'St. Catharine''s', 'St. Edmund''s', 'Selwyn', 'Sidney Sussex', 'Trinity Hall', 'Wolfson'};
            app.College_10.Value = 'Murray Edwards';
            app.Label_23 = uilabel(app.BoatInfo);
            app.Label_23.Text = '';
            app.Speed_10 = uieditfield(app.BoatInfo, 'numeric');
            app.Speed_10.Value = 500;
            app.Label_24 = uilabel(app.BoatInfo);
            app.Label_24.Text = '11';
            app.College_11 = uidropdown(app.BoatInfo);
            app.College_11.Items = {'Anglia Ruskin', 'Caius', 'Christ''s', 'Churchill', 'Clare', 'Clare Hall', 'Corpus Christi', 'Darwin', 'Downing', 'Emmanuel', 'First and Third', 'Fitzwilliam', 'Girton', 'Homerton', 'Hughes Hall', 'Jesus', 'King''s', 'Lady Margaret', 'Lucy Cavendish', 'Magdalene', 'Murray Edwards', 'Newnham', 'Pembroke', 'Peterhouse', 'Queens''', 'Robinson', 'St. Catharine''s', 'St. Edmund''s', 'Selwyn', 'Sidney Sussex', 'Trinity Hall', 'Wolfson'};
            app.College_11.Value = 'Murray Edwards';
            app.Label_25 = uilabel(app.BoatInfo);
            app.Label_25.Text = '';
            app.Speed_11 = uieditfield(app.BoatInfo, 'numeric');
            app.Speed_11.Value = 500;
            app.Label_26 = uilabel(app.BoatInfo);
            app.Label_26.Text = '12';
            app.College_12 = uidropdown(app.BoatInfo);
            app.College_12.Items = {'Anglia Ruskin', 'Caius', 'Christ''s', 'Churchill', 'Clare', 'Clare Hall', 'Corpus Christi', 'Darwin', 'Downing', 'Emmanuel', 'First and Third', 'Fitzwilliam', 'Girton', 'Homerton', 'Hughes Hall', 'Jesus', 'King''s', 'Lady Margaret', 'Lucy Cavendish', 'Magdalene', 'Murray Edwards', 'Newnham', 'Pembroke', 'Peterhouse', 'Queens''', 'Robinson', 'St. Catharine''s', 'St. Edmund''s', 'Selwyn', 'Sidney Sussex', 'Trinity Hall', 'Wolfson'};
            app.College_12.Value = 'Murray Edwards';
            app.Label_27 = uilabel(app.BoatInfo);
            app.Label_27.Text = '';
            app.Speed_12 = uieditfield(app.BoatInfo, 'numeric');
            app.Speed_12.Value = 500;
            app.Label_28 = uilabel(app.BoatInfo);
            app.Label_28.Text = '13';
            app.College_13 = uidropdown(app.BoatInfo);
            app.College_13.Items = {'Anglia Ruskin', 'Caius', 'Christ''s', 'Churchill', 'Clare', 'Clare Hall', 'Corpus Christi', 'Darwin', 'Downing', 'Emmanuel', 'First and Third', 'Fitzwilliam', 'Girton', 'Homerton', 'Hughes Hall', 'Jesus', 'King''s', 'Lady Margaret', 'Lucy Cavendish', 'Magdalene', 'Murray Edwards', 'Newnham', 'Pembroke', 'Peterhouse', 'Queens''', 'Robinson', 'St. Catharine''s', 'St. Edmund''s', 'Selwyn', 'Sidney Sussex', 'Trinity Hall', 'Wolfson'};
            app.College_13.Value = 'Murray Edwards';
            app.Label_29 = uilabel(app.BoatInfo);
            app.Label_29.Text = '';
            app.Speed_13 = uieditfield(app.BoatInfo, 'numeric');
            app.Speed_13.Value = 500;
            app.Label_30 = uilabel(app.BoatInfo);
            app.Label_30.Text = '14';
            app.College_14 = uidropdown(app.BoatInfo);
            app.College_14.Items = {'Anglia Ruskin', 'Caius', 'Christ''s', 'Churchill', 'Clare', 'Clare Hall', 'Corpus Christi', 'Darwin', 'Downing', 'Emmanuel', 'First and Third', 'Fitzwilliam', 'Girton', 'Homerton', 'Hughes Hall', 'Jesus', 'King''s', 'Lady Margaret', 'Lucy Cavendish', 'Magdalene', 'Murray Edwards', 'Newnham', 'Pembroke', 'Peterhouse', 'Queens''', 'Robinson', 'St. Catharine''s', 'St. Edmund''s', 'Selwyn', 'Sidney Sussex', 'Trinity Hall', 'Wolfson'};
            app.College_14.Value = 'Murray Edwards';
            app.Label_31 = uilabel(app.BoatInfo);
            app.Label_31.Text = '';
            app.Speed_14 = uieditfield(app.BoatInfo, 'numeric');
            app.Speed_14.Value = 500;
            app.Label_32 = uilabel(app.BoatInfo);
            app.Label_32.Text = '15';
            app.College_15 = uidropdown(app.BoatInfo);
            app.College_15.Items = {'Anglia Ruskin', 'Caius', 'Christ''s', 'Churchill', 'Clare', 'Clare Hall', 'Corpus Christi', 'Darwin', 'Downing', 'Emmanuel', 'First and Third', 'Fitzwilliam', 'Girton', 'Homerton', 'Hughes Hall', 'Jesus', 'King''s', 'Lady Margaret', 'Lucy Cavendish', 'Magdalene', 'Murray Edwards', 'Newnham', 'Pembroke', 'Peterhouse', 'Queens''', 'Robinson', 'St. Catharine''s', 'St. Edmund''s', 'Selwyn', 'Sidney Sussex', 'Trinity Hall', 'Wolfson'};
            app.College_15.Value = 'Murray Edwards';
            app.Label_33 = uilabel(app.BoatInfo);
            app.Label_33.Text = '';
            app.Speed_15 = uieditfield(app.BoatInfo, 'numeric');
            app.Speed_15.Value = 500;
            app.Label_34 = uilabel(app.BoatInfo);
            app.Label_34.Text = '16';
            app.College_16 = uidropdown(app.BoatInfo);
            app.College_16.Items = {'Anglia Ruskin', 'Caius', 'Christ''s', 'Churchill', 'Clare', 'Clare Hall', 'Corpus Christi', 'Darwin', 'Downing', 'Emmanuel', 'First and Third', 'Fitzwilliam', 'Girton', 'Homerton', 'Hughes Hall', 'Jesus', 'King''s', 'Lady Margaret', 'Lucy Cavendish', 'Magdalene', 'Murray Edwards', 'Newnham', 'Pembroke', 'Peterhouse', 'Queens''', 'Robinson', 'St. Catharine''s', 'St. Edmund''s', 'Selwyn', 'Sidney Sussex', 'Trinity Hall', 'Wolfson'};
            app.College_16.Value = 'Murray Edwards';
            app.Label_35 = uilabel(app.BoatInfo);
            app.Label_35.Text = '';
            app.Speed_16 = uieditfield(app.BoatInfo, 'numeric');
            app.Speed_16.Value = 500;
            app.Label_36 = uilabel(app.BoatInfo);
            app.Label_36.Text = '17';
            app.College_17 = uidropdown(app.BoatInfo);
            app.College_17.Items = {'Anglia Ruskin', 'Caius', 'Christ''s', 'Churchill', 'Clare', 'Clare Hall', 'Corpus Christi', 'Darwin', 'Downing', 'Emmanuel', 'First and Third', 'Fitzwilliam', 'Girton', 'Homerton', 'Hughes Hall', 'Jesus', 'King''s', 'Lady Margaret', 'Lucy Cavendish', 'Magdalene', 'Murray Edwards', 'Newnham', 'Pembroke', 'Peterhouse', 'Queens''', 'Robinson', 'St. Catharine''s', 'St. Edmund''s', 'Selwyn', 'Sidney Sussex', 'Trinity Hall', 'Wolfson'};
            app.College_17.Value = 'Murray Edwards';
            app.Label_37 = uilabel(app.BoatInfo);
            app.Label_37.Text = '';
            app.Speed_17 = uieditfield(app.BoatInfo, 'numeric');
            app.Speed_17.Value = 500;
            app.RaceButtonGroup = uibuttongroup(app.BumpsSimulatorUIFigure);
            app.RaceButtonGroup.Title = 'Race';
            app.MenButton = uitogglebutton(app.RaceButtonGroup);
            app.MenButton.Text = 'Men';
            app.WomenButton = uitogglebutton(app.RaceButtonGroup);
            app.WomenButton.Text = 'Women';
            app.WomenButton.Value = true;
            app.DivisionButtonGroup = uibuttongroup(app.BumpsSimulatorUIFigure);
            app.DivisionButtonGroup.Title = 'Division';
            app.Button = uitogglebutton(app.DivisionButtonGroup);
            app.Button.Text = '1';
            app.Button.Value = true;
            app.Button_2 = uitogglebutton(app.DivisionButtonGroup);
            app.Button_2.Text = '2';
            app.Button_3 = uitogglebutton(app.DivisionButtonGroup);
            app.Button_3.Text = '3';
            app.Button_4 = uitogglebutton(app.DivisionButtonGroup);
            app.Button_4.Text = '4';
            app.Button_5 = uitogglebutton(app.DivisionButtonGroup);
            app.Button_5.Text = '5';
            app.DaysSpinnerLabel = uilabel(app.BumpsSimulatorUIFigure);
            app.DaysSpinnerLabel.Text = 'Days';
            app.DaysSpinner = uispinner(app.BumpsSimulatorUIFigure);
            app.DaysSpinner.Limits = [1 10];
            app.DaysSpinner.RoundFractionalValues = 'on';
            app.DaysSpinner.Value = 4;
            app.BoatsSpinnerLabel = uilabel(app.BumpsSimulatorUIFigure);
            app.BoatsSpinnerLabel.Text = 'Boats';
            app.BoatsSpinner = uispinner(app.BumpsSimulatorUIFigure);
            app.BoatsSpinner.Limits = [1 17];
            app.BoatsSpinner.RoundFractionalValues = 'on';
            app.BoatsSpinner.Value = 17;
            app.UpdateTableButton = uibutton(app.BumpsSimulatorUIFigure, 'push');
            app.UpdateTableButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateTableButtonPushed, true);
            app.UpdateTableButton.Text = 'Update Table';
            app.BoatInfo_2 = uipanel(app.BumpsSimulatorUIFigure);
            app.BoatInfo_2.Title = 'Results';
            app.Result_1 = uilabel(app.BoatInfo_2);
            app.Result_1.Text = '';
            app.Result_2 = uilabel(app.BoatInfo_2);
            app.Result_2.Text = '';
            app.Result_3 = uilabel(app.BoatInfo_2);
            app.Result_3.Text = '';
            app.Result_4 = uilabel(app.BoatInfo_2);
            app.Result_4.Text = '';
            app.Result_5 = uilabel(app.BoatInfo_2);
            app.Result_5.Text = '';
            app.Result_6 = uilabel(app.BoatInfo_2);
            app.Result_6.Text = '';
            app.Result_7 = uilabel(app.BoatInfo_2);
            app.Result_7.Text = '';
            app.Result_8 = uilabel(app.BoatInfo_2);
            app.Result_8.Text = '';
            app.Result_9 = uilabel(app.BoatInfo_2);
            app.Result_9.Text = '';
            app.Result_10 = uilabel(app.BoatInfo_2);
            app.Result_10.Text = '';
            app.Result_11 = uilabel(app.BoatInfo_2);
            app.Result_11.Text = '';
            app.Result_12 = uilabel(app.BoatInfo_2);
            app.Result_12.Text = '';
            app.Result_13 = uilabel(app.BoatInfo_2);
            app.Result_13.Text = '';
            app.Result_14 = uilabel(app.BoatInfo_2);
            app.Result_14.Text = '';
            app.Result_15 = uilabel(app.BoatInfo_2);
            app.Result_15.Text = '';
            app.Result_16 = uilabel(app.BoatInfo_2);
            app.Result_16.Text = '';
            app.Result_17 = uilabel(app.BoatInfo_2);
            app.Result_17.Text = '';
            app.AboutButton = uibutton(app.BumpsSimulatorUIFigure, 'push');
            app.AboutButton.ButtonPushedFcn = createCallbackFcn(app, @AboutButtonPushed, true);
            app.AboutButton.Text = 'About';
            app.TermButtonGroup = uibuttongroup(app.BumpsSimulatorUIFigure);
            app.TermButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TermButtonGroupSelectionChanged, true);
            app.TermButtonGroup.Title = 'Term';
            app.LentButton = uitogglebutton(app.TermButtonGroup);
            app.LentButton.Text = 'Lent';
            app.MayButton = uitogglebutton(app.TermButtonGroup);
            app.MayButton.Text = 'May';
            app.MayButton.Value = true;
            app.YearSpinnerLabel = uilabel(app.BumpsSimulatorUIFigure);
            app.YearSpinnerLabel.Text = 'Year';
            app.YearSpinner = uispinner(app.BumpsSimulatorUIFigure);
            app.YearSpinner.Limits = [2011 2019];
            app.YearSpinner.RoundFractionalValues = 'on';
            app.YearSpinner.ValueChangedFcn = createCallbackFcn(app, @YearSpinnerValueChanged, true);
            app.YearSpinner.Value = 2019;
            % Show the figure after all components are created
            app.BumpsSimulatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Bumps4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BumpsSimulatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BumpsSimulatorUIFigure)
        end
    end
end